classdef graficos < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        ValoresButton    matlab.ui.control.Button
        ValoresButton_3  matlab.ui.control.Button
        ValoresButton_2  matlab.ui.control.Button
        PararButton_3    matlab.ui.control.Button
        IniciarButton_3  matlab.ui.control.Button
        PararButton_2    matlab.ui.control.Button
        IniciarButton_2  matlab.ui.control.Button
        IniciarButton    matlab.ui.control.Button
        PararButton      matlab.ui.control.Button
        UIAxes3          matlab.ui.control.UIAxes
        UIAxes2          matlab.ui.control.UIAxes
        UIAxes           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ReadingX = false;
        ReadingY = false;
        ReadingZ = false;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: IniciarButton
        function IniciarButtonPushed(app, event)
            set(app.IniciarButton, 'Enable', 'off') 
            app.ReadingX = true;
            while app.ReadingX == true
            i=1;
                while i
                % Chama a função Comandos_STIM com a opção 6
                    [val, ~] = Comandos_STIM(6);
                    eixox(i) = val;
        
                    if i<=500
                    %if i
                       % eixo(i)=val;
                    % Atualiza o gráfico do eixo X com o valor lido
                        plot(app.UIAxes, eixox, 'Linewidth',1);

                         if val >= 23
                            Comandos_STIM(9);
                         end
                    
                    end
                    i = i+1;
                end
            end            
        end

        % Button pushed function: PararButton
        function PararButtonPushed(app, event)
            set(app.IniciarButton, 'Enable', 'on')
            app.ReadingX = false;
        end

        % Button pushed function: IniciarButton_2
        function IniciarButton_2Pushed(app, event)
            set(app.IniciarButton_2, 'Enable', 'off') 
            app.ReadingY = true;
            while app.ReadingY == true
            i=1;
                while i
                % Chama a função Comandos_STIM com a opção 7
                    [val, ~] = Comandos_STIM(7);
                    eixoy(i) = val;
        
                    if i<=500
                    %if i
                       % eixo(i)=val;
                    % Atualiza o gráfico do eixo X com o valor lido
                        plot(app.UIAxes2, eixoy, 'Linewidth',1);

                        if val >= 23
                            Comandos_STIM(9);
                        end
                
                    end
                    i = i+1;
                end
            end                 
        end

        % Button pushed function: PararButton_2
        function PararButton_2Pushed(app, event)
            set(app.IniciarButton_2, 'Enable', 'on')
            app.ReadingY = false;   
            drawnow;
            
            pause(0.1); % Simulação de um processo acontecend
        end

        % Button pushed function: IniciarButton_3
        function IniciarButton_3Pushed(app, event)
            set(app.IniciarButton_3, 'Enable', 'off') 
            app.ReadingZ = true;
            while app.ReadingZ == true
            i=1;
                while i
                % Chama a função Comandos_STIM com a opção 8
                    [val, ~] = Comandos_STIM(8);
                    eixoz(i) = val;
        
                    if i<=500
                    %if i
                       % eixo(i)=val;
                    % Atualiza o gráfico do eixo X com o valor lido
                        plot(app.UIAxes3, eixoz, 'Linewidth',1);
 
                        if val >= 23
                            Comandos_STIM(9);
                        end
                
                    end
                    i = i+1;
                end
            end     
        end

        % Button pushed function: PararButton_3
        function PararButton_3Pushed(app, event)
            set(app.IniciarButton_3, 'Enable', 'on')
            app.ReadingZ = false;              
        end

        % Button pushed function: ValoresButton
        function ValoresButtonPushed(app, event)
            valoresx;
        end

        % Button pushed function: ValoresButton_2
        function ValoresButton_2Pushed(app, event)
            valoresy;
        end

        % Button pushed function: ValoresButton_3
        function ValoresButton_3Pushed(app, event)
            valoresz;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Eixo x')
            xlabel(app.UIAxes, 'Nº Amostras')
            ylabel(app.UIAxes, 'Aceleração (m/s^2)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Eixo y')
            xlabel(app.UIAxes2, 'Nº Amostras')
            ylabel(app.UIAxes2, 'Aceleração (m/s^2)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Eixo z')
            xlabel(app.UIAxes3, 'Nº Amostras')
            ylabel(app.UIAxes3, 'Aceleração (m/s^2)')
            zlabel(app.UIAxes3, 'Z')
            app.PararButton = uibutton(app.UIFigure, 'push');
            app.PararButton.ButtonPushedFcn = createCallbackFcn(app, @PararButtonPushed, true);
            app.PararButton.Text = 'Parar';
            app.IniciarButton = uibutton(app.UIFigure, 'push');
            app.IniciarButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarButtonPushed, true);
            app.IniciarButton.Text = 'Iniciar';
            app.IniciarButton_2 = uibutton(app.UIFigure, 'push');
            app.IniciarButton_2.ButtonPushedFcn = createCallbackFcn(app, @IniciarButton_2Pushed, true);
            app.IniciarButton_2.Text = 'Iniciar';
            app.PararButton_2 = uibutton(app.UIFigure, 'push');
            app.PararButton_2.ButtonPushedFcn = createCallbackFcn(app, @PararButton_2Pushed, true);
            app.PararButton_2.Text = 'Parar';
            app.IniciarButton_3 = uibutton(app.UIFigure, 'push');
            app.IniciarButton_3.ButtonPushedFcn = createCallbackFcn(app, @IniciarButton_3Pushed, true);
            app.IniciarButton_3.Text = 'Iniciar';
            app.PararButton_3 = uibutton(app.UIFigure, 'push');
            app.PararButton_3.ButtonPushedFcn = createCallbackFcn(app, @PararButton_3Pushed, true);
            app.PararButton_3.Text = 'Parar';
            app.ValoresButton_2 = uibutton(app.UIFigure, 'push');
            app.ValoresButton_2.ButtonPushedFcn = createCallbackFcn(app, @ValoresButton_2Pushed, true);
            app.ValoresButton_2.Text = 'Valores';
            app.ValoresButton_3 = uibutton(app.UIFigure, 'push');
            app.ValoresButton_3.ButtonPushedFcn = createCallbackFcn(app, @ValoresButton_3Pushed, true);
            app.ValoresButton_3.Text = 'Valores';
            app.ValoresButton = uibutton(app.UIFigure, 'push');
            app.ValoresButton.ButtonPushedFcn = createCallbackFcn(app, @ValoresButtonPushed, true);
            app.ValoresButton.Text = 'Valores';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = graficos

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end