classdef menu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TextArea                    matlab.ui.control.TextArea
        OKButton                    matlab.ui.control.Button
        EscolhaumaopoDropDown       matlab.ui.control.DropDown
        EscolhaumaopoDropDownLabel  matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        ANLISEDAMARCHALabel         matlab.ui.control.Label
        VerGrficosButton            matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: VerGrficosButton
        function VerGrficosButtonPushed(app, event)
            graficos;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
             switch app.EscolhaumaopoDropDown.Value
                case 'MetaTeds'
                    [val] = Comandos_STIM(1);
                    val1=[];
                    val2=[];
                    val3=[];

                  for i=1:6
                    val1 = [val1, val(i)];
                  end
                
                  for i=7:18
                    val2 = [val2, val(i)];
                  end
                
                  for i=19:22
                    val3 = [val3, val(i)];
                  end
                    Res = ['Meta TED ID:' , num2str(val1)];
                    Res1 = ['Meta TED UUID: ', num2str(val2)];
                    Res2 = ['Meta TED Max Channels: ', num2str(val3)];
                    Res3 = [];
                    Res4 = [];
                    Res5 = [];
                    Res6 = [];
                    Res7 = [];


                case 'Teds referentes ao eixo x'
                   [val] = Comandos_STIM(2);
                   val1=[];
                   val2=[];
                   val3=[];
                   val4=[];
                   val5=[];
                   val6=[];
                   val7=[];
                   val8=[];
        
                   for i=1:6
                       val1 = [val1, val(i)];
                   end
        
                   for i=7:9
                       val2 = [val2, val(i)];
                   end
        
                   for i=10:21
                       val3 = [val3, val(i)];
                   end
        
                   for i=22:24
                       val4 = [val4, val(i)];
                   end
        
                   for i=25:27
                       val5 = [val5, val(i)];
                   end
        
                   for i=28:30
                       val6 = [val6, val(i)];
                   end
        
                   for i=31:33
                       val7 = [val7, val(i)];
                   end
        
                   for i=34:36
                       val8 = [val8, val(i)];
                   end

                    Res = ['TransducerChannel 1 TEDS ID: ', num2str(val1)];
                    Res1 = ['TransducerChannel 1 TEDS Channel Type: ', num2str(val2)];
                    Res2 = ['TransducerChannel 1 Units: ', num2str(val3)];
                    Res3 = ['TransducerChannel 1 TEDS Low range limit: ', num2str(val4)];
                    Res4 = ['TransducerChannel 1 TEDS High range limit: ', num2str(val5)];
                    Res5 = ['TransducerChannel 1 TEDS Data model: ', num2str(val6)];
                    Res6 = ['TransducerChannel 1 TEDS Data model length: ', num2str(val7)];
                    Res7 = ['TransducerChannel 1 TEDS Data model significant bits: ', num2str(val8)];
                    
                    
                case 'Teds referentes ao eixo y'
                   [val] = Comandos_STIM(3);
                   val1=[];
                   val2=[];
                   val3=[];
                   val4=[];
                   val5=[];
                   val6=[];
                   val7=[];
                   val8=[];
        
                   for i=1:6
                       val1 = [val1, val(i)];
                   end
        
                   for i=7:9
                       val2 = [val2, val(i)];
                   end
        
                   for i=10:21
                       val3 = [val3, val(i)];
                   end
        
                   for i=22:24
                       val4 = [val4, val(i)];
                   end
        
                   for i=25:27
                       val5 = [val5, val(i)];
                   end
        
                   for i=28:30
                       val6 = [val6, val(i)];
                   end
        
                   for i=31:33
                       val7 = [val7, val(i)];
                   end
        
                   for i=34:36
                       val8 = [val8, val(i)];
                   end

                    Res = ['TransducerChannel 2 TEDS ID: ', num2str(val1)];
                    Res1 = ['TransducerChannel 2 TEDS Channel Type: ', num2str(val2)];
                    Res2 = ['TransducerChannel 2 Units: ', num2str(val3)];
                    Res3 = ['TransducerChannel 2 TEDS Low range limit: ', num2str(val4)];
                    Res4 = ['TransducerChannel 2 TEDS High range limit: ', num2str(val5)];
                    Res5 = ['TransducerChannel 2 TEDS Data model: ', num2str(val6)];
                    Res6 = ['TransducerChannel 2 TEDS Data model length: ', num2str(val7)];
                    Res7 = ['TransducerChannel 2 TEDS Data model significant bits: ', num2str(val8)];
                    
                case 'Teds referentes ao eixo z'
                   [val] = Comandos_STIM(4);
                   val1=[];
                   val2=[];
                   val3=[];
                   val4=[];
                   val5=[];
                   val6=[];
                   val7=[];
                   val8=[];
        
                   for i=1:6
                       val1 = [val1, val(i)];
                   end
        
                   for i=7:9
                       val2 = [val2, val(i)];
                   end
        
                   for i=10:21
                       val3 = [val3, val(i)];
                   end
        
                   for i=22:24
                       val4 = [val4, val(i)];
                   end
        
                   for i=25:27
                       val5 = [val5, val(i)];
                   end
        
                   for i=28:30
                       val6 = [val6, val(i)];
                   end
        
                   for i=31:33
                       val7 = [val7, val(i)];
                   end
        
                   for i=34:36
                       val8 = [val8, val(i)];
                   end

                    Res = ['TransducerChannel 3 TEDS ID: ', num2str(val1)];
                    Res1 = ['TransducerChannel 3 TEDS Channel Type: ', num2str(val2)];
                    Res2 = ['TransducerChannel 3 Units: ', num2str(val3)];
                    Res3 = ['TransducerChannel 3 TEDS Low range limit: ', num2str(val4)];
                    Res4 = ['TransducerChannel 3 TEDS High range limit: ', num2str(val5)];
                    Res5 = ['TransducerChannel 3 TEDS Data model: ', num2str(val6)];
                    Res6 = ['TransducerChannel 3 TEDS Data model length: ', num2str(val7)];
                    Res7 = ['TransducerChannel 3 TEDS Data model significant bits: ', num2str(val8)];

                 case 'Teds referentes ao atuador'
                   [val] = Comandos_STIM(5);
                   val1=[];
                   val2=[];
                   val3=[];
                   val4=[];
                   val5=[];
                   val6=[];
                   val7=[];
                   val8=[];
        
                   for i=1:6
                       val1 = [val1, val(i)];
                   end
        
                   for i=7:9
                       val2 = [val2, val(i)];
                   end
        
                   for i=10:21
                       val3 = [val3, val(i)];
                   end
        
                   for i=22:24
                       val4 = [val4, val(i)];
                   end
        
                   for i=25:27
                       val5 = [val5, val(i)];
                   end
        
                   for i=28:30
                       val6 = [val6, val(i)];
                   end
        
                   for i=31:33
                       val7 = [val7, val(i)];
                   end
        
                   for i=34:36
                       val8 = [val8, val(i)];
                   end

                    Res = ['TransducerChannel 4 TEDS ID: ', num2str(val1)];
                    Res1 = ['TransducerChannel 4 TEDS Channel Type: ', num2str(val2)];
                    Res2 = ['TransducerChannel 4 Units: ', num2str(val3)];
                    Res3 = ['TransducerChannel 4 TEDS Low range limit: ', num2str(val4)];
                    Res4 = ['TransducerChannel 4 TEDS High range limit: ', num2str(val5)];
                    Res5 = ['TransducerChannel 4 TEDS Data model: ', num2str(val6)];
                    Res6 = ['TransducerChannel 4 TEDS Data model length: ', num2str(val7)];
                    Res7 = ['TransducerChannel 4 TEDS Data model significant bits: ', num2str(val8)];


            end
            app.TextArea.Value = [Res, newline, Res1, newline, Res2, newline, Res3, newline, Res4, newline, Res5, newline, Res6, newline, Res7];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.VerGrficosButton = uibutton(app.UIFigure, 'push');
            app.VerGrficosButton.ButtonPushedFcn = createCallbackFcn(app, @VerGrficosButtonPushed, true);
            app.VerGrficosButton.Text = 'Ver Gráficos';
            app.ANLISEDAMARCHALabel = uilabel(app.UIFigure);
            app.ANLISEDAMARCHALabel.Text = 'ANÁLISE DA MARCHA';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '🚶‍♂️';
            app.EscolhaumaopoDropDownLabel = uilabel(app.UIFigure);
            app.EscolhaumaopoDropDownLabel.Text = 'Escolha uma opção';
            app.EscolhaumaopoDropDown = uidropdown(app.UIFigure);
            app.EscolhaumaopoDropDown.Items = {'MetaTeds', 'Teds referentes ao eixo x', 'Teds referentes ao eixo y', 'Teds referentes ao eixo z', 'Teds referentes ao atuador'};
            app.EscolhaumaopoDropDown.Value = 'MetaTeds';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = menu

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end