classdef valoresz < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        TextArea       matlab.ui.control.TextArea
        IniciarButton  matlab.ui.control.Button
        ValoresreferentesaoeixodozLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        Readingz = false;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: IniciarButton
        function IniciarButtonPushed(app, event)
            set(app.IniciarButton, 'Enable', 'off')
            app.Readingz = true;
            valuesReceived = {}; % Cria uma célula vazia para armazenar os valores recebidos
            
            while app.Readingz == true
                i = 1;
                while i
                    % Chama a função Comandos_STIM com a opção 8
                    [val, ~] = Comandos_STIM(8);
                    Res = num2str(val);
                    
                    % Adiciona o valor recebido ao registro
                    valuesReceived{end+1} = Res;
                    
                    % Atualiza a área de texto com todos os valores recebidos até agora
                    app.TextArea.Value = strjoin(valuesReceived, '\n');
                    
                    i = i + 1;
                end
            end
        end

        % Callback function
        function PararButtonPushed(app, event)
            set(app.IniciarButton, 'Enable', 'on')    
            app.Readingz = false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ValoresreferentesaoeixodozLabel = uilabel(app.UIFigure);
            app.ValoresreferentesaoeixodozLabel.Text = 'Valores referentes ao eixo do z';
            app.IniciarButton = uibutton(app.UIFigure, 'push');
            app.IniciarButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarButtonPushed, true);
            app.IniciarButton.Text = 'Iniciar';
            app.TextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = valoresz

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end